classdef simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel_2          matlab.ui.container.Panel
        Button_2         matlab.ui.control.Button
        DropDown_8       matlab.ui.control.DropDown
        DropDown_8Label  matlab.ui.control.Label
        DropDown_7       matlab.ui.control.DropDown
        DropDown_7Label  matlab.ui.control.Label
        DropDown_6       matlab.ui.control.DropDown
        DropDown_6Label  matlab.ui.control.Label
        DropDown_5       matlab.ui.control.DropDown
        DropDown_5Label  matlab.ui.control.Label
        Panel            matlab.ui.container.Panel
        Button           matlab.ui.control.Button
        DropDown_4       matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        DropDown_3       matlab.ui.control.DropDown
        Label_3          matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        DropDown_2       matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        UIAxes2_2        matlab.ui.control.UIAxes
        UIAxes1_2        matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes1          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function result(app, event)
            x=(-5:0.1:5).*1e-3;            % 坐标范围
            lambda=632.8e-9;               % 波长
            L=10;                          % 腔长
            X=x.*sqrt(2*pi./(lambda.*L));  	
            [X1,Y1]=meshgrid(X,X);         % X和Y的坐标矩阵

              % 获取选择的横模类型
            selectedModeType = app.DropDown_4.Value;
            if selectedModeType == 'TEM₀₀'
                H0X=1;
                H0Y=1;
                FX0=H0X.*exp(-X1.^2-2);
                FY0=H0Y.*exp(-Y1.^2-2);
                u00=FX0.*FY0;        
                I2=FX0.^2.*FY0.^2;
                imagesc(app.UIAxes1, abs(u00));
                surf(app.UIAxes2,X1,Y1,I2,'EdgeColor', 'none');
            elseif selectedModeType == 'TEM₀₁'
                H0X=1;
                H1Y=2.*Y1;
                FX0=H0X.*exp(-X1.^2-2);
                FY1=H1Y.*exp(-Y1.^2-2);
                u01=FX0.*FY1;         
                I1=FX0.^2.*FY1.^2;
                imagesc(app.UIAxes1,abs(u01));
                surf(app.UIAxes2,X1,Y1,I1,'EdgeColor', 'none');
            elseif selectedModeType == 'TEM₁₁'
                H1X=2.*X1;
                H1Y=2.*Y1;
                FX1=H1X.*exp(-X1.^2-2);
                FY1=H1Y.*exp(-Y1.^2-2);
                u11=FX1.*FY1;      
                I2=FX1.^2.*FY1.^2;
                imagesc(app.UIAxes1,abs(u11))   
                surf(app.UIAxes2,X1,Y1,I2,'EdgeColor', 'none')
            elseif selectedModeType == 'TEM₂₂'
                H2X=4.*X1.^2-2;
                H2Y=4.*Y1.^2-2;
                FX2=H2X.*exp(-X1.^2-2);
                FY2=H2Y.*exp(-Y1.^2-2);
                u22=FX2.*FY2;       
                I3=FX2.^2.*FY2.^2;
                imagesc(app.UIAxes1,abs(u22));
                surf(app.UIAxes2,X1,Y1,I3,'EdgeColor', 'none');
            end

        end

        % Button pushed function: Button_2
        function result2(app, event)
            xRange = [-0.005 0.005];
            yRange = [-0.005 0.005];

            % 设置采样点数
            Nx = 200;
            Ny = 200;

            % 生成坐标网格
            [x, y] = meshgrid(linspace(xRange(1), xRange(2), Nx), linspace(yRange(1), yRange(2), Ny));
            w0 = 0.001; % 束腰半径（单位：米）
            k = 2 * pi / 632.8e-9; % 波数，这里假设波长为632.8nm
            % 计算拉盖尔-高斯函数的强度分布
            r = sqrt(x.^2 + y.^2);
            theta = atan2(y, x);
            ExponentialPart = exp(-r.^2 / w0^2);

            selectedModeType = app.DropDown_8.Value;
            if selectedModeType == 'TEM₀₀'
                p0 = 0; 
                l0 = 0;
                LaguerrePoly00 = laguerreL(p0, abs(l0), 2 * r.^2 / w0^2);
                AmplitudeLG00 = sqrt(2 * factorial(p0) / (pi * factorial(p0 + abs(l0))))...
                .* (sqrt(2) * r / w0).^abs(l0).* LaguerrePoly00.* ExponentialPart;
                PhaseLG00 = l0 * theta;
                ComplexAmplitudeLG00 = AmplitudeLG00.* exp(1i * PhaseLG00);
                IntensityLG00 = abs(ComplexAmplitudeLG00).^2;
                imagesc(app.UIAxes1_2,xRange, yRange, IntensityLG00);
                surf(app.UIAxes2_2,x, y, IntensityLG00,'EdgeColor', 'none');
            elseif selectedModeType == 'TEM₀₁'
                p0 = 0;
                l1 = 1;
                LaguerrePoly01 = laguerreL(p0, abs(l1), 2 * r.^2 / w0^2);
                AmplitudeLG01 = sqrt(2 * factorial(p0) / (pi * factorial(p0 + abs(l1))))...
                .* (sqrt(2) * r / w0).^abs(l1).* LaguerrePoly01.* ExponentialPart;
                PhaseLG01 = l1 * theta;
                ComplexAmplitudeLG01 = AmplitudeLG01.* exp(1i * PhaseLG01);
                IntensityLG01 = abs(ComplexAmplitudeLG01).^2;
                imagesc(app.UIAxes1_2,xRange, yRange, IntensityLG01);
                surf(app.UIAxes2_2,x, y, IntensityLG01,'EdgeColor', 'none');
            elseif selectedModeType == 'TEM₁₁'
                p1 = 1;
                l1 = 1;
                LaguerrePoly11 = laguerreL(p1, abs(l1), 2 * r.^2 / w0^2);
                AmplitudeLG11 = sqrt(2 * factorial(p1) / (pi * factorial(p1 + abs(l1))))...
                    .* (sqrt(2) * r / w0).^abs(l1).* LaguerrePoly11.* ExponentialPart;
                PhaseLG11 = l1 * theta;
                ComplexAmplitudeLG11 = AmplitudeLG11.* exp(1i * PhaseLG11);
                IntensityLG11 = abs(ComplexAmplitudeLG11).^2;
                imagesc(app.UIAxes1_2,xRange, yRange, IntensityLG11);
                surf(app.UIAxes2_2,x, y, IntensityLG11,'EdgeColor', 'none');
            elseif selectedModeType == 'TEM₂₂'
                p2 = 2;
                l2 = 2;
                LaguerrePoly22 = laguerreL(p2, abs(l2), 2 * r.^2 / w0^2);
                AmplitudeLG22 = sqrt(2 * factorial(p2) / (pi * factorial(p2 + abs(l2))))...
                    .* (sqrt(2) * r / w0).^abs(l2).* LaguerrePoly22.* ExponentialPart;
                PhaseLG22 = l2 * theta;
                ComplexAmplitudeLG22 = AmplitudeLG22.* exp(1i * PhaseLG22);
                IntensityLG22 = abs(ComplexAmplitudeLG22).^2;
                imagesc(app.UIAxes1_2,xRange, yRange, IntensityLG22);
                surf(app.UIAxes2_2,x, y, IntensityLG22,'EdgeColor', 'none');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '横模')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '光强分布的三维模型')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1_2 = uiaxes(app.UIFigure);
            title(app.UIAxes1_2, '横模')
            xlabel(app.UIAxes1_2, 'X')
            ylabel(app.UIAxes1_2, 'Y')
            zlabel(app.UIAxes1_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, '光强分布的三维模型')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '激光模式仿真1';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '类型';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'方形镜共焦腔'};
            app.DropDown_2.Value = '方形镜共焦腔';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '腔长';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'10cm'};
            app.DropDown.Value = '10cm';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '激光波长';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'632.8e-9'};
            app.DropDown_3.Value = '632.8e-9';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '模式';
            app.DropDown_4 = uidropdown(app.Panel);
            app.DropDown_4.Items = {'TEM₀₀', 'TEM₀₁', 'TEM₁₁', 'TEM₂₂'};
            app.DropDown_4.Value = 'TEM₀₀';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @result, true);
            app.Button.Text = '生成图像';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '激光模式仿真2';
            app.DropDown_5Label = uilabel(app.Panel_2);
            app.DropDown_5Label.Text = '类型';
            app.DropDown_5 = uidropdown(app.Panel_2);
            app.DropDown_5.Items = {'圆形镜共焦腔'};
            app.DropDown_5.Value = '圆形镜共焦腔';
            app.DropDown_6Label = uilabel(app.Panel_2);
            app.DropDown_6Label.Text = '腔长';
            app.DropDown_6 = uidropdown(app.Panel_2);
            app.DropDown_6.Items = {'10cm'};
            app.DropDown_6.Value = '10cm';
            app.DropDown_7Label = uilabel(app.Panel_2);
            app.DropDown_7Label.Text = '激光波长';
            app.DropDown_7 = uidropdown(app.Panel_2);
            app.DropDown_7.Items = {'632.8e-9'};
            app.DropDown_7.Value = '632.8e-9';
            app.DropDown_8Label = uilabel(app.Panel_2);
            app.DropDown_8Label.Text = '模式';
            app.DropDown_8 = uidropdown(app.Panel_2);
            app.DropDown_8.Items = {'TEM₀₀', 'TEM₀₁', 'TEM₁₁', 'TEM₂₂'};
            app.DropDown_8.Value = 'TEM₀₀';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @result2, true);
            app.Button_2.Text = '生成图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end